classdef Editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        GridLayout           matlab.ui.container.GridLayout
        ResolveMeshButton    matlab.ui.control.Button
        AlphaEditFieldLabel  matlab.ui.control.Label
        AlphaEditField       matlab.ui.control.NumericEditField
        ImportButton         matlab.ui.control.Button
        Slider               matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        AddButton_2          matlab.ui.control.Button
        LayerSpinner         matlab.ui.control.Spinner
        LayerSpinnerLabel    matlab.ui.control.Label
        ShapesListBox        matlab.ui.control.ListBox
        EditShapeButton      matlab.ui.control.Button
        MeshButton           matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        AddButton            matlab.ui.control.Button
        ObjectsListBox       matlab.ui.control.ListBox
        ROI                  matlab.ui.control.UIAxes
        Img                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgs % Description
        current % Description
        alpha_parm % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imgs = {};
            app.current = 0;
            app.alpha_parm = inf;
            pbaspect(app.Img,[1 1 1]);
            pbaspect(app.ROI,[1 1 1]);
        end

        % Button pushed function: AddButton
        function addImg(app, event)
            [file,path] = uigetfile("*.png");
            
            if file ~= 0
                app.current = length(app.imgs)+1;
                [img,~,mask]=imread(append(path,file));
                app.imgs{app.current} = {file,img,mask,{}};
                %app.ObjectsListBox.Items{1+length(app.ObjectsListBox.Items)} = {str(file)};
            
                image(app.Img,app.imgs{app.current}{2});
                    
                [W,H,~]=size(img);
                
                app.Img.XLim = [-inf,inf];
                app.Img.YLim = [-inf,inf];
                
                app.ROI.YDir = 'reverse';    
                app.ROI.XLim = [1,W];
                app.ROI.YLim = [1,H];
            end
        end

        % Button pushed function: ResolveMeshButton
        function ResolveMesh(app, event)
            ROI_points = app.imgs{app.current}{4};
            num_points = length(ROI_points);
            points = zeros(num_points,2);
            for i = 1:num_points
                points(i,1) = ROI_points{i}.Position(1);
                points(i,2) = ROI_points{i}.Position(2);
            end
            app.imgs{app.current}{5} = points;
            mesh = point2mesh(points,app.alpha_parm);
            app.imgs{app.current}{6} = mesh;
            [num_mesh,~] = size(mesh);
            
            image(app.Img,app.imgs{app.current}{2});
            hold(app.Img,"on");
            for i = 1:num_mesh
                idx_vertex = mesh(i,:);
                plot(app.Img,[points(idx_vertex,1); points(idx_vertex(1),1)],[points(idx_vertex,2); points(idx_vertex(1),2)])
            end
            hold(app.Img,"off");
            
            %app.imgs{app.current}{5} = {};
            %app.imgs{app.current}{6} = {};
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.alpha_parm = app.AlphaEditField.Value;
        end

        % Callback function
        function AddPoint(app, event)
            if app.current ~= 0
                app.imgs{app.current}{4}{1+length(app.imgs{app.current}{4})} = drawpoint(app.ROI);   
            end    
        end

        % Button down function: ROI
        function ROIButtonDown2(app, event)
            if app.current ~= 0
                app.imgs{app.current}{4}{1+length(app.imgs{app.current}{4})} = drawpoint(app.ROI);   
            end    
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile;
            if file ~= 0
                exportflie = app.imgs;
                save(append(path,file),"exportflie",'-mat');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Img = uiaxes(app.GridLayout);
            xlabel(app.Img, 'X')
            ylabel(app.Img, 'Y')
            zlabel(app.Img, 'Z')
            app.ROI = uiaxes(app.GridLayout);
            xlabel(app.ROI, 'X')
            ylabel(app.ROI, 'Y')
            zlabel(app.ROI, 'Z')
            app.ROI.ButtonDownFcn = createCallbackFcn(app, @ROIButtonDown2, true);
            app.ObjectsListBox = uilistbox(app.GridLayout);
            app.ObjectsListBox.Items = {};
            app.ObjectsListBox.Value = {};
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addImg, true);
            app.AddButton.Text = 'Add';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.MeshButton = uibutton(app.GridLayout, 'push');
            app.MeshButton.Text = 'Mesh';
            app.EditShapeButton = uibutton(app.GridLayout, 'push');
            app.EditShapeButton.Text = 'Edit Shape';
            app.ShapesListBox = uilistbox(app.GridLayout);
            app.ShapesListBox.Items = {};
            app.ShapesListBox.Value = {};
            app.LayerSpinnerLabel = uilabel(app.GridLayout);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.GridLayout);
            app.AddButton_2 = uibutton(app.GridLayout, 'push');
            app.AddButton_2.Text = 'Add';
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.Text = 'Import';
            app.AlphaEditField = uieditfield(app.GridLayout, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = Inf;
            app.AlphaEditFieldLabel = uilabel(app.GridLayout);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.ResolveMeshButton = uibutton(app.GridLayout, 'push');
            app.ResolveMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ResolveMesh, true);
            app.ResolveMeshButton.Text = 'Resolve Mesh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end